classdef Washing_Machine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptimizedDesignParametersLabel  matlab.ui.control.Label
        SchematicButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        maxgenEditField                 matlab.ui.control.NumericEditField
        maxgenEditFieldLabel            matlab.ui.control.Label
        OptimiseButton                  matlab.ui.control.Button
        ConstantVariablesLabel          matlab.ui.control.Label
        DesignVariablesLabel            matlab.ui.control.Label
        rEditField                      matlab.ui.control.NumericEditField
        rEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        muEditField                     matlab.ui.control.NumericEditField
        muEditFieldLabel                matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        zdEditField                     matlab.ui.control.NumericEditField
        zdEditFieldLabel                matlab.ui.control.Label
        xdEditField                     matlab.ui.control.NumericEditField
        xdEditFieldLabel                matlab.ui.control.Label
        zsEditField                     matlab.ui.control.NumericEditField
        zsEditFieldLabel                matlab.ui.control.Label
        xsEditField                     matlab.ui.control.NumericEditField
        xsEditFieldLabel                matlab.ui.control.Label
        cEditField                      matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        kEditField                      matlab.ui.control.NumericEditField
        kEditFieldLabel                 matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        k0 % Description
        c0 % Description
        xs0 % Description
        zs0 % Description
        xd0 % Description
        zd0 % Description
        maxgen % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            app.k0 = app.kEditField.Value;
        end

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)
            app.c0 = app.cEditField.Value;
        end

        % Value changed function: xsEditField
        function xsEditFieldValueChanged(app, event)
            app.xs0 = app.xsEditField.Value;
        end

        % Value changed function: zsEditField
        function zsEditFieldValueChanged(app, event)
            app.zs0 = app.zsEditField.Value;
        end

        % Value changed function: xdEditField
        function xdEditFieldValueChanged(app, event)
            app.xd0 = app.xdEditField.Value;
        end

        % Value changed function: zdEditField
        function zdEditFieldValueChanged(app, event)
            app.zd0 = app.zdEditField.Value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %Constant Matrix

            m = app.mEditField.Value;         %Mass of Washing Machine
            mu = app.muEditField.Value;       %Unbalanced Mass
            r = app.rEditField.Value;         %Radius
            N = app.NEditField.Value*pi/60;
            
            constant_mat = [m; mu; r; N];
            assignin("base","constant_mat",constant_mat)
            
            k = app.k0; %Spring Constant
            c = app.c0; %Damping Constant
            xs1 = app.xs0; zs1 = app.zs0;
            xd1 = app.xd0; zd1 = app.zd0;

            design_mat0 = [k; c; xs1; zs1; xd1; zd1];
            
            [~,column] = size(design_mat0);
            for i = 1:column
                design_mat = design_mat0(:,i);
                assignin("base","design_mat",design_mat)
                xz_values = sim('Project_Model2021a',[0 10]);     % for MATLAB Version 2021a
%               xz_values = sim('Project_Model2020b',[0 10]);     % for MATLAB Version 2020b
%               xz_values = sim('Project_Model2020a',[0 10]);     % for MATLAB Version 2020a
            end
            
            % x vs time
            plot(app.UIAxes,xz_values.tout,xz_values.xz([1:end-1],1))
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'Time (in sec)')
            ylabel(app.UIAxes,'X Position (in m)')
            title(app.UIAxes,'Displacement in X vs Time')
            
            % xForce vs time
            plot(app.UIAxes_2,xz_values(1,1).tout,xz_values(1,1).force_x([1:end-1],1))
            grid(app.UIAxes_2,'on');
            xlabel(app.UIAxes_2,'Time (in sec)')
            ylabel(app.UIAxes_2,'X Force (in N)')
            title(app.UIAxes_2,'Force in X vs Time')
            
            % z vs time
            plot(app.UIAxes_3,xz_values(1,1).tout,xz_values(1,1).xz([1:end-1],2))
            grid(app.UIAxes_3,'on');
            xlabel(app.UIAxes_3,'Time (in sec)')
            ylabel(app.UIAxes_3,'Z Position (in m)')
            title(app.UIAxes_3,'Displacement in Z vs Time')
            
            % zForce vs time
            plot(app.UIAxes_4,xz_values(1,1).tout,xz_values(1,1).force_z([1:end-1],1))
            grid(app.UIAxes_4,'on');
            xlabel(app.UIAxes_4,'Time (in sec)')
            ylabel(app.UIAxes_4,'Z Force (in N)')
            title(app.UIAxes_4,'Force in Z vs Time')
            
            clear design_mat
            clear constant_mat
          
        end

        % Button pushed function: OptimiseButton
        function OptimiseButtonPushed(app, event)
            % Constant Matrix

            m = app.mEditField.Value;         % Mass of Washing Machine
            mu = app.muEditField.Value;       % Unbalanced Mass
            r = app.rEditField.Value;         % Radius
            N = app.NEditField.Value*pi/60;
            
            constant_mat = [m; mu; r; N];
            assignin("base","constant_mat",constant_mat)
            
            % Controling paramters of the GA algortihm
            Problem.obj = @fitness_fn;
            Problem.nVar = 18;
            
            M = 20;              % Number of chromosomes (cadinate solutions)
            N = Problem.nVar;    % Number of genes (variables)
            MaxGen = app.maxgen;
            Pc = 0.85;
            Pm = 0.01;
            Er = 0.05;
            visualization = 0;   % Set to 0 if you do not want the convergence curve 
            
            [BestChrom]  = GeneticAlgorithm (M , N, MaxGen , Pc, Pm , Er , Problem.obj , visualization);
            disp('1')
            disp('The best chromosome found: ')
            gene = BestChrom.Gene;
            disp('The best fitness value: ')
            bestfit = BestChrom.Fitness;
            
            mat = decoder_array(gene);
            design_mat0 = mat(1,:);
            design_mat0 = design_mat0';
            
            app.k0 = design_mat0(1);
            app.c0 = design_mat0(2);
            app.xs0 = design_mat0(3);
            app.zs0 = design_mat0(4);
            app.xd0 = design_mat0(5);
            app.zd0 = design_mat0(6);
            
            PlotButtonPushed(app, event)
            
            app.UITable.Data = [design_mat0' bestfit];
            
        end

        % Value changed function: maxgenEditField
        function maxgenEditFieldValueChanged(app, event)
            app.maxgen = app.maxgenEditField.Value;
        end

        % Button pushed function: SchematicButton
        function SchematicButtonPushed(app, event)
            figure('Name','Washing Machine Schematic')
            A = imread("Schematic2.png");
            image(A);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Displacement in X vs Time')
            xlabel(app.UIAxes, 'Time (in sec)')
            ylabel(app.UIAxes, 'Displacement in X(in m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Force in X vs Time')
            xlabel(app.UIAxes_2, 'Time (in sec)')
            ylabel(app.UIAxes_2, 'Force in X(in N)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Displacement in Z vs Time')
            xlabel(app.UIAxes_3, 'Time (in sec)')
            ylabel(app.UIAxes_3, 'Displacement in Z(in m)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Force in Z vs Time')
            xlabel(app.UIAxes_4, 'Time (in sec)')
            ylabel(app.UIAxes_4, 'Force in Z(in N)')
            zlabel(app.UIAxes_4, 'Z')
            app.kEditFieldLabel = uilabel(app.UIFigure);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.UIFigure, 'numeric');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.xsEditFieldLabel = uilabel(app.UIFigure);
            app.xsEditFieldLabel.Text = 'xs';
            app.xsEditField = uieditfield(app.UIFigure, 'numeric');
            app.xsEditField.ValueChangedFcn = createCallbackFcn(app, @xsEditFieldValueChanged, true);
            app.zsEditFieldLabel = uilabel(app.UIFigure);
            app.zsEditFieldLabel.Text = 'zs';
            app.zsEditField = uieditfield(app.UIFigure, 'numeric');
            app.zsEditField.ValueChangedFcn = createCallbackFcn(app, @zsEditFieldValueChanged, true);
            app.xdEditFieldLabel = uilabel(app.UIFigure);
            app.xdEditFieldLabel.Text = 'xd';
            app.xdEditField = uieditfield(app.UIFigure, 'numeric');
            app.xdEditField.ValueChangedFcn = createCallbackFcn(app, @xdEditFieldValueChanged, true);
            app.zdEditFieldLabel = uilabel(app.UIFigure);
            app.zdEditFieldLabel.Text = 'zd';
            app.zdEditField = uieditfield(app.UIFigure, 'numeric');
            app.zdEditField.ValueChangedFcn = createCallbackFcn(app, @zdEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            app.muEditFieldLabel = uilabel(app.UIFigure);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesignVariablesLabel = uilabel(app.UIFigure);
            app.DesignVariablesLabel.Text = 'Design Variables';
            app.ConstantVariablesLabel = uilabel(app.UIFigure);
            app.ConstantVariablesLabel.Text = 'Constant Variables';
            app.OptimiseButton = uibutton(app.UIFigure, 'push');
            app.OptimiseButton.ButtonPushedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.OptimiseButton.Text = 'Optimise';
            app.maxgenEditFieldLabel = uilabel(app.UIFigure);
            app.maxgenEditFieldLabel.Text = 'max gen';
            app.maxgenEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxgenEditField.ValueChangedFcn = createCallbackFcn(app, @maxgenEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'K'; 'C'; 'xs'; 'zs'; 'xd'; 'zd'; 'Best Fitness'};
            app.UITable.RowName = {};
            app.SchematicButton = uibutton(app.UIFigure, 'push');
            app.SchematicButton.ButtonPushedFcn = createCallbackFcn(app, @SchematicButtonPushed, true);
            app.SchematicButton.Text = 'Schematic';
            app.OptimizedDesignParametersLabel = uilabel(app.UIFigure);
            app.OptimizedDesignParametersLabel.Text = 'Optimized Design Parameters ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Washing_Machine

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end